classdef mini_pneumatics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SimpleControlPanel         matlab.ui.container.Panel
        PositivePumpSwitchLabel    matlab.ui.control.Label
        PositivePumpSwitch         matlab.ui.control.ToggleSwitch
        NegativePumpSwitchLabel    matlab.ui.control.Label
        NegativePumpSwitch         matlab.ui.control.ToggleSwitch
        PosValveSwitchLabel        matlab.ui.control.Label
        PosValveSwitch             matlab.ui.control.ToggleSwitch
        NegValveSwitchLabel        matlab.ui.control.Label
        NegValveSwitch             matlab.ui.control.ToggleSwitch
        ExhaustValveSwitchLabel    matlab.ui.control.Label
        ExhaustValveSwitch         matlab.ui.control.ToggleSwitch
        Gauge                      matlab.ui.control.Gauge
        PressurekPaEditFieldLabel  matlab.ui.control.Label
        PressurekPaEditField       matlab.ui.control.EditField
        deflateButton              matlab.ui.control.Button
        exhuastButton              matlab.ui.control.Button
        inflateButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        pneumatics =serial('/dev/cu.wchusbserial14130',"Baudrate",9600);
    end
    
    properties (Access = private)
        
        myTimer % Description
    end
    
    methods (Access = public)
        function  pull_pressures(app)
        
            fprintf(app.pneumatics,'<PR>');
            p_resp = fscanf(app.pneumatics);
            app.PressurekPaEditField.Value = p_resp;
            app.Gauge.Value = str2num(p_resp);
        end
    end
    
    methods (Access = private)
  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fprintf('delete(instrfind)')
            fopen(app.pneumatics);
            
            app.myTimer = timer('Period',0.1,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf, "StartDelay",10);
app.myTimer.TimerFcn = @(~,~)app.pull_pressures;
start(app.myTimer);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
        delete(instrfind);  
        delete(app)
            
        end

        % Value changed function: PositivePumpSwitch
        function PositivePumpSwitchValueChanged(app, event)
            value = app.PositivePumpSwitch.Value;
            if (value=="Off")
                fprintf(app.pneumatics,'<P,0,0>')
            else
                fprintf(app.pneumatics,'<P,0,1>')
            end
        end

        % Value changed function: NegativePumpSwitch
        function NegativePumpSwitchValueChanged(app, event)
            value = app.NegativePumpSwitch.Value;
            if (value=="Off")
                fprintf(app.pneumatics,'<P,1,0>')
            else
                fprintf(app.pneumatics,'<P,1,1>')
            end
        end

        % Value changed function: PosValveSwitch
        function PosValveSwitchValueChanged(app, event)
            value = app.PosValveSwitch.Value;
            if (value=="Off")
                fprintf(app.pneumatics,'<V,0,0>')
            else
                fprintf(app.pneumatics,'<V,0,1>')
            end
        end

        % Value changed function: NegValveSwitch
        function NegValveSwitchValueChanged(app, event)
            value = app.NegValveSwitch.Value;
            if (value=="Off")
                fprintf(app.pneumatics,'<V,1,0>')
            else
                fprintf(app.pneumatics,'<V,1,1>')
            end
        end

        % Value changed function: ExhaustValveSwitch
        function ExhaustValveSwitchValueChanged(app, event)
            value = app.ExhaustValveSwitch.Value;
            if (value=="Off")
                fprintf(app.pneumatics,'<V,2,0>')
            else
                fprintf(app.pneumatics,'<V,2,1>')
            end
        end

        % Callback function
        function RetrievePressureButtonPushed(app, event)
            app.pull_pressures
        end

        % Button pushed function: inflateButton
        function inflateButtonPushed(app, event)
            
        end

        % Callback function: UIFigure, deflateButton
        function deflateButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @deflateButtonPushed, true);
            app.SimpleControlPanel = uipanel(app.UIFigure);
            app.SimpleControlPanel.Title = 'Simple Control';
            app.PositivePumpSwitchLabel = uilabel(app.SimpleControlPanel);
            app.PositivePumpSwitchLabel.Text = 'Positive Pump';
            app.PositivePumpSwitch = uiswitch(app.SimpleControlPanel, 'toggle');
            app.PositivePumpSwitch.ValueChangedFcn = createCallbackFcn(app, @PositivePumpSwitchValueChanged, true);
            app.NegativePumpSwitchLabel = uilabel(app.SimpleControlPanel);
            app.NegativePumpSwitchLabel.Text = 'Negative Pump';
            app.NegativePumpSwitch = uiswitch(app.SimpleControlPanel, 'toggle');
            app.NegativePumpSwitch.ValueChangedFcn = createCallbackFcn(app, @NegativePumpSwitchValueChanged, true);
            app.PosValveSwitchLabel = uilabel(app.SimpleControlPanel);
            app.PosValveSwitchLabel.Text = 'Pos Valve';
            app.PosValveSwitch = uiswitch(app.SimpleControlPanel, 'toggle');
            app.PosValveSwitch.ValueChangedFcn = createCallbackFcn(app, @PosValveSwitchValueChanged, true);
            app.NegValveSwitchLabel = uilabel(app.SimpleControlPanel);
            app.NegValveSwitchLabel.Text = 'Neg Valve';
            app.NegValveSwitch = uiswitch(app.SimpleControlPanel, 'toggle');
            app.NegValveSwitch.ValueChangedFcn = createCallbackFcn(app, @NegValveSwitchValueChanged, true);
            app.ExhaustValveSwitchLabel = uilabel(app.SimpleControlPanel);
            app.ExhaustValveSwitchLabel.Text = 'Exhaust Valve';
            app.ExhaustValveSwitch = uiswitch(app.SimpleControlPanel, 'toggle');
            app.ExhaustValveSwitch.ValueChangedFcn = createCallbackFcn(app, @ExhaustValveSwitchValueChanged, true);
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.Limits = [-100 100];
            app.PressurekPaEditFieldLabel = uilabel(app.UIFigure);
            app.PressurekPaEditFieldLabel.Text = 'Pressure (kPa)';
            app.PressurekPaEditField = uieditfield(app.UIFigure, 'text');
            app.PressurekPaEditField.Editable = 'off';
            app.inflateButton = uibutton(app.UIFigure, 'push');
            app.inflateButton.ButtonPushedFcn = createCallbackFcn(app, @inflateButtonPushed, true);
            app.inflateButton.Text = 'inflate';
            app.deflateButton = uibutton(app.UIFigure, 'push');
            app.deflateButton.ButtonPushedFcn = createCallbackFcn(app, @deflateButtonPushed, true);
            app.deflateButton.Text = 'deflate';
            app.exhuastButton = uibutton(app.UIFigure, 'push');
            app.exhuastButton.Text = 'exhuast';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mini_pneumatics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end